classdef DomBot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LastLogTextAreaLabel   matlab.ui.control.Label
        LastLogTextArea        matlab.ui.control.TextArea
        DomBotLabel            matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        SimulationButton       matlab.ui.control.StateButton
        LogWARNButton          matlab.ui.control.StateButton
        LogERRORButton         matlab.ui.control.StateButton
        LogFATALButton         matlab.ui.control.StateButton
        LogDEBUGButton         matlab.ui.control.StateButton
        LogINFOButton          matlab.ui.control.StateButton
        DominosSliderLabel     matlab.ui.control.Label
        DominosSlider          matlab.ui.control.Slider
        DominosCount           matlab.ui.control.Label
        LogtoFileSwitchLabel   matlab.ui.control.Label
        LogtoFileSwitch        matlab.ui.control.Switch
        EStopButton            matlab.ui.control.StateButton
        RunButton              matlab.ui.control.Button
        TeachDropDownLabel     matlab.ui.control.Label
        TeachDropDown          matlab.ui.control.DropDown
        DominoPathButtonGroup  matlab.ui.container.ButtonGroup
        CircleButton           matlab.ui.control.RadioButton
        SemiCircleButton       matlab.ui.control.RadioButton
        UserDefinedButton      matlab.ui.control.RadioButton
        StraightLineButton     matlab.ui.control.RadioButton
        ModeDropDownLabel      matlab.ui.control.Label
        ModeDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        logObj      % Log object
        simOb       % Simulation object
    end
    
    methods (Access = private)
        % Log DEBUG
        function LogDebug(app,txt)
            if(app.LogDEBUGButton.Value); app.DisplayLog([datestr(now,'HH:MM:SS'),' DEBUG: [APP] ',txt]); end              
        end
        % Log INFO
        function LogInfo(app,txt)
            if(app.LogINFOButton.Value); app.DisplayLog([datestr(now,'HH:MM:SS'),' INFO: [APP] ',txt]); end 
        end
        % Log WARN
        function LogWarn(app,txt)
            if(app.LogWARNButton.Value); app.DisplayLog([datestr(now,'HH:MM:SS'),' WARN: [APP] ',txt]); end
        end
        % Log ERROR
        function LogError(app,txt)
            if(app.LogERRORButton.Value); app.DisplayLog([datestr(now,'HH:MM:SS'),' ERROR: [APP] ',txt]); end
        end
        % Log FATAL
        function LogFatal(app,txt)
            if(app.LogFATALButton.Value); app.DisplayLog([datestr(now,'HH:MM:SS'),' FATAL: [APP] ',txt]); end 
        end
        % Display Log
        function DisplayLog(app,txt)
            disp(txt);                                                      % Display in command window 
            app.LastLogTextArea.Value = txt;                                % Display in app
        end
        % Get the selected path identifier
        function path = getSelectedDominoPath(app)
            if(app.SemiCircleButton.Value)
                path = 2; % Semi-Circle
                return;
            end
            if(app.StraightLineButton.Value)
                path = 3; % Line
                return;
            end
            % Failed to set other path - Set to Circle
            path = 1; % Circle
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SimulationButton
        function SimulationButtonValueChanged(app, event)
            value = app.SimulationButton.Value;
            if(value)
                clc;    % Clear the command window
                clf;    % Clear the current figure
                %close;  % Close current figure
                % Disable GUI buttons
                app.CircleButton.Enable = 'off';
                app.SemiCircleButton.Enable = 'off';
                app.StraightLineButton.Enable = 'off';
                
                % Create log object
                app.logObj = Log();                                            % Call Log constructor
                % Set log flags on simulation start trigger
                app.logObj.SetDebugFlag(app.LogDEBUGButton.Value);
                app.logObj.SetInfoFlag(app.LogINFOButton.Value);
                app.logObj.SetWarnFlag(app.LogWARNButton.Value);
                app.logObj.SetErrorFlag(app.LogERRORButton.Value);
                app.logObj.SetFatalFlag(app.LogFATALButton.Value);
                app.logObj.SetLog2FileFlag(app.LogtoFileSwitch.Value);                

                
                % Create sim object passing in log object & dominosTotal
                app.simOb = Simulation(app.logObj, round(app.DominosSlider.Value));  % Call Simulation constructor
                app.LogInfo('Simulation Started (sim constructor)')                 % Send start message to LOG
                
                % Place the system in a simulated environment (Github #5)
                GenerateEnvironment(app.simOb);     % Generate walls & floor surfaces
                SpawnEnvironmentObjects(app.simOb); % Spawn environment objects
                
                % Set eStop value
                app.simOb.SetSimEStopFlag(app.EStopButton.Value);
                
                % Set the simulation to run
                SetSimRunningFlag(app.simOb, true);
                
                % Set up simulation
                SetUpSim(app.simOb, app.getSelectedDominoPath());
                
                % Enable GUI buttons
                app.EStopButton.Enable = 'on';
                app.TeachDropDown.Enable = 'on';
                app.RunButton.Enable = 'on';
                app.ModeDropDown.Enable = 'on';
            else    
                % Set the simulation to stop
                SetSimRunningFlag(app.simOb, false);
                app.LogInfo('Simulation Finished')         % Send finish message to LOG
                
                % Enable GUI buttons
                app.CircleButton.Enable = 'on';
                app.SemiCircleButton.Enable = 'on';
                app.StraightLineButton.Enable = 'on';
                % Disable GUI buttons
                app.EStopButton.Enable = 'off';
                app.RunButton.Enable = 'off';
                app.TeachDropDown.Enable = 'off';
                app.TeachDropDown.Value = '-';
                app.ModeDropDown.Enable = 'off';
                app.ModeDropDown.Value = 'Normal';
                
                %app.LogInfo('Simulation Finished (sim destructor)')         % Send finish message to LOG
                %delete(app.simOb);
                %% Call destructor - THIS DOESNT WORK AS CALLED, IGNORE FOR NOW
            end
        end

        % Value changed function: LogDEBUGButton
        function LogDEBUGButtonValueChanged(app, event)
            value = app.LogDEBUGButton.Value;
            try
                app.logObj.SetDebugFlag(value);                
            catch ME
                app.LogWarn('Log DEBUG state changed | Simulation not running');
            end
        end

        % Value changed function: LogINFOButton
        function LogINFOButtonValueChanged(app, event)
            value = app.LogINFOButton.Value;
            try
                app.logObj.SetInfoFlag(value);                
            catch ME
                app.LogWarn('Log INFO state changed | Simulation not running');
            end           
        end

        % Value changed function: LogWARNButton
        function LogWARNButtonValueChanged(app, event)
            value = app.LogWARNButton.Value;
            try
                app.logObj.SetWarnFlag(value);                
            catch ME
                app.LogWarn('Log WARN state changed | Simulation not running');
            end
        end

        % Value changed function: LogERRORButton
        function LogERRORButtonValueChanged(app, event)
            value = app.LogERRORButton.Value;
            try
                app.logObj.SetErrorFlag(value);                
            catch ME
                app.LogWarn('Log Error state changed | Simulation not running');
            end
        end

        % Value changed function: LogFATALButton
        function LogFATALButtonValueChanged(app, event)
            value = app.LogFATALButton.Value;
            try
                app.logObj.SetFatalFlag(value);                
            catch ME
                app.LogWarn('Log FATAL state changed | Simulation not running');
            end
        end

        % Value changed function: DominosSlider
        function DominosSliderValueChanged(app, event)
            app.DominosCount.Text = string(round(app.DominosSlider.Value));
            
        end

        % Value changed function: LogtoFileSwitch
        function LogtoFileSwitchValueChanged(app, event)
            try
                app.logObj.SetLog2FileFlag(app.LogtoFileSwitch.Value);                
            catch ME
                app.LogWarn('Log to File state changed | Simulation not running');
            end
        end

        % Value changed function: EStopButton
        function EStopButtonValueChanged(app, event)
            value = app.EStopButton.Value;
            app.simOb.SetSimEStopFlag(value);
            % When setting the estop either on or off the expected
            % functionality is for the simulation to NOT run. Therefore
            % regardless of the value set simRunning flag to false
            app.simOb.SetSimRunningFlag(false);
            % Enable/disable continue button based on estop button change.
            if(value)
                % Disable continue button
                app.RunButton.Enable = 'off';
            else
                % Enable continue button
                app.RunButton.Enable = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Disable RUN button
            app.RunButton.Enable = 'off';
            app.simOb.SetSimRunningFlag(true);
            app.simOb.RunSim();
        end

        % Value changed function: TeachDropDown
        function TeachDropDownValueChanged(app, event)
            value = app.TeachDropDown.Value;
            switch(value)
                case 'Classic'
                    app.simOb.StartClassicTeach();
                case 'Cartesian'
                    app.simOb.StartCartesianTeach();
                case 'Object'
                    app.simOb.StartObjectTeach();
                case 'Person'
                    app.simOb.StartPersonTeach();
                otherwise
                    % Do nothing
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            switch(value)
                case 'Normal'
                    % Set retreat flag false
                    app.simOb.SetRetreatFlag(false);
                case 'Retreat'
                    % Set retreat flag true
                    app.simOb.SetRetreatFlag(true);
                otherwise
                    % Do nothing
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LastLogTextAreaLabel = uilabel(app.UIFigure);
            app.LastLogTextAreaLabel.Text = 'Last Log';
            app.LastLogTextArea = uitextarea(app.UIFigure);
            app.DomBotLabel = uilabel(app.UIFigure);
            app.DomBotLabel.Text = 'DomBot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Koda Enoka - 12580748 | Nathanael Gandhi - 12966382 | Travis Goodshaw - 12912770';
            app.SimulationButton = uibutton(app.UIFigure, 'state');
            app.SimulationButton.ValueChangedFcn = createCallbackFcn(app, @SimulationButtonValueChanged, true);
            app.SimulationButton.Text = 'Simulation';
            app.LogWARNButton = uibutton(app.UIFigure, 'state');
            app.LogWARNButton.ValueChangedFcn = createCallbackFcn(app, @LogWARNButtonValueChanged, true);
            app.LogWARNButton.Text = 'Log WARN';
            app.LogWARNButton.Value = true;
            app.LogERRORButton = uibutton(app.UIFigure, 'state');
            app.LogERRORButton.ValueChangedFcn = createCallbackFcn(app, @LogERRORButtonValueChanged, true);
            app.LogERRORButton.Text = 'Log ERROR';
            app.LogERRORButton.Value = true;
            app.LogFATALButton = uibutton(app.UIFigure, 'state');
            app.LogFATALButton.ValueChangedFcn = createCallbackFcn(app, @LogFATALButtonValueChanged, true);
            app.LogFATALButton.Text = 'Log FATAL';
            app.LogFATALButton.Value = true;
            app.LogDEBUGButton = uibutton(app.UIFigure, 'state');
            app.LogDEBUGButton.ValueChangedFcn = createCallbackFcn(app, @LogDEBUGButtonValueChanged, true);
            app.LogDEBUGButton.Text = 'Log DEBUG';
            app.LogDEBUGButton.Value = true;
            app.LogINFOButton = uibutton(app.UIFigure, 'state');
            app.LogINFOButton.ValueChangedFcn = createCallbackFcn(app, @LogINFOButtonValueChanged, true);
            app.LogINFOButton.Text = 'Log INFO';
            app.LogINFOButton.Value = true;
            app.DominosSliderLabel = uilabel(app.UIFigure);
            app.DominosSliderLabel.Text = 'Dominos';
            app.DominosSlider = uislider(app.UIFigure);
            app.DominosSlider.Limits = [15 45];
            app.DominosSlider.ValueChangedFcn = createCallbackFcn(app, @DominosSliderValueChanged, true);
            app.DominosSlider.Value = 15;
            app.DominosCount = uilabel(app.UIFigure);
            app.DominosCount.Text = '15';
            app.LogtoFileSwitchLabel = uilabel(app.UIFigure);
            app.LogtoFileSwitchLabel.Text = 'Log to File';
            app.LogtoFileSwitch = uiswitch(app.UIFigure, 'slider');
            app.LogtoFileSwitch.ItemsData = [false true];
            app.LogtoFileSwitch.ValueChangedFcn = createCallbackFcn(app, @LogtoFileSwitchValueChanged, true);
            app.LogtoFileSwitch.Value = true;
            app.EStopButton = uibutton(app.UIFigure, 'state');
            app.EStopButton.ValueChangedFcn = createCallbackFcn(app, @EStopButtonValueChanged, true);
            app.EStopButton.Enable = 'off';
            app.EStopButton.Text = 'E-Stop';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.TeachDropDownLabel = uilabel(app.UIFigure);
            app.TeachDropDownLabel.Enable = 'off';
            app.TeachDropDownLabel.Text = 'Teach';
            app.TeachDropDown = uidropdown(app.UIFigure);
            app.TeachDropDown.Items = {'-', 'Classic', 'Cartesian', 'Object', 'Person'};
            app.TeachDropDown.ValueChangedFcn = createCallbackFcn(app, @TeachDropDownValueChanged, true);
            app.TeachDropDown.Enable = 'off';
            app.TeachDropDown.Value = '-';
            app.DominoPathButtonGroup = uibuttongroup(app.UIFigure);
            app.DominoPathButtonGroup.Title = 'Domino Path';
            app.CircleButton = uiradiobutton(app.DominoPathButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.SemiCircleButton = uiradiobutton(app.DominoPathButtonGroup);
            app.SemiCircleButton.Text = 'Semi-Circle';
            app.UserDefinedButton = uiradiobutton(app.DominoPathButtonGroup);
            app.UserDefinedButton.Enable = 'off';
            app.UserDefinedButton.Text = 'User-Defined';
            app.StraightLineButton = uiradiobutton(app.DominoPathButtonGroup);
            app.StraightLineButton.Text = 'Straight Line';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Enable = 'off';
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Normal', 'Retreat'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Enable = 'off';
            app.ModeDropDown.Value = 'Normal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DomBot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end